classdef DockerPref < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        LoadPresetsMenu             matlab.ui.container.Menu
        SavePresetsMenu             matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        GetPrefMenu                 matlab.ui.container.Menu
        SetPrefMenu                 matlab.ui.container.Menu
        ValidatePrefMenu            matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        ISETDockerPrefsEditorLabel  matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        ISETDockerPrefPanel         matlab.ui.container.Panel
        PrefsPanelGrid              matlab.ui.container.GridLayout
        EditFieldsCheckBox          matlab.ui.control.CheckBox
        ValidateButton              matlab.ui.control.Button
        GetPrefButton               matlab.ui.control.Button
        AddasPresetButton           matlab.ui.control.Button
        SetPrefButton               matlab.ui.control.Button
        PfFieldsPanel               matlab.ui.container.Panel
        PfFieldsPanelGrid           matlab.ui.container.GridLayout
        PresetsPanel                matlab.ui.container.Panel
        PresetPanelGrid             matlab.ui.container.GridLayout
        DeletePresetButton          matlab.ui.control.Button
        CopytoPrefButton            matlab.ui.control.Button
        PresetDropDown              matlab.ui.control.DropDown
        PresetDropDownLabel         matlab.ui.control.Label
        PsFieldsPanel               matlab.ui.container.Panel
        PsFieldsPanelGrid           matlab.ui.container.GridLayout
    end

    
    properties (Constant, Access = private)
        dockerPresetTemplate =  struct( ...
            'label', "", ...
            'renderContext', "", ...
            'remoteHost', "", ...
            'device', "", ...
            'deviceID', "", ...
            'dockerImage', "", ...
            'remoteUser', "", ...
            'workDir', "", ...
            'PBRTResources', "" ...
        );
        emergencyDefault = struct ( ...
            'label', "Emergency Default", ...
            'renderContext', "default", ...
            'remoteHost', "", ...
            'device', "cpu", ...
            'deviceID', "", ...
            'dockerImage', "vistalab/pbrt-v4-cpu", ...
            'remoteUser', "", ...
            'workDir', "", ...
            'PBRTResources', "" ...            
        );

        editableColor = [1 1 1];
        lockedColor = [0.90 0.90 0.90];

        aliasFields = struct( ...
            'deviceId',         'deviceID', ...
            'deviceID',         'deviceId' ...
        );
        ignoreFields = {
            'PBRTContainer', ...
            'batch', ...
            'remotePBRTResources'
        };

    end

    properties (Access = private)
        % The data structures that the app uses
       
        userName                    % local username
        sitePresets                 % site-wide presets file
        myPresets                   % personalized presets file
        dockerPresets = struct([])  % an array of presets
        dockerPresetsChanged = false;       % did we change anything?
        activePresetsTable          % manipulate indices instead of array
        curIdx                      % index into activePresetsTable

        presetGridHandle = struct();% the grid
        presetFields = struct();    % the EditFields
        
        curPrefs = struct();        % the current ISETDocker prefs 
        curPrefsChanged = false;
        prefGridHandle = struct();
        prefFields = struct();
        
        ignoredPrefs = struct();

    end
    
    methods (Access = private)
        %% Read the presets from the docker/config directory.
        function  getDockerPresets(app)
           
            %
            % what should we have as static defaults?  A local cpu version?
            % See if there's a json file that we can read
            % once we've dealt with the json file we can see if there is
            % an ISETDocker pref that wants to be made into a preset
            % if yes, read it in and build the menu
            % %otherwise
            %
            
            % get USER and other useful stuff 
            % where do we want to stash it?
            %
        
            if isfile(app.myPresets)
                presetsFile = app.myPresets;
            elseif isfile(app.sitePresets)
                warning('Personal presets file %s not found, trying site presets', app.myPresets);
                presetsFile = app.sitePresets;
            else
                % change this to a warning and generate some generic
                % presets
                warning('Site presets file %s not found either', app.sitePresets);
                presetsFile = "";
            end   

            if ~isempty(presetsFile)
                try
                    rawtext = fileread(presetsFile);
                    decodedData = jsondecode(rawtext);

                    if ~isstruct(decodedData) || isempty(decodedData)
                        warning('JSON file %s is empty or has invalid structure.', presetsFile);
                        decodedData = [];
                    end
                catch err
                    warning(err.identifier, 'Error reading JSON file %s: %s', presetsFile, err.message);
                    decodedData = [];
                end
            else
                decodedData = [];
            end
            % jsondecode turns all the strings into char arrays.  Now we're
            % going to switch them back to strings.  While we're there
            % we'll strip leading and excess spaces. We may want to do some validation as well.

            if ~isempty(decodedData)
                decodedData = app.convertDecodedPresets(decodedData);

                % Now we have happy, productive preset structures. So we slap
                % them against the template structure to force matlab to check
                % fields names and types
                try
                    newPresets = [app.dockerPresetTemplate([]) ; decodedData];
                catch concaterr
                    % if we figure out something useful to do, we'll implement
                    % salvagePresetsFile and make this a warning
                    error(concaterr.identifier, 'Structure mismatch in JSON file: %s', concaterr.message);
                    newPresets = salvagePresetsFile(app, decodedData);
                end
            else
                newPresets = [];
            end
            if isempty(newPresets)
                newPresets = [ app.dockerPresetTemplate([]) ; app.emergencyDefault ];
                app.dockerPresetsChanged = true;
            end
            % Now set up dockerPresets we'll allocate more later if a new
            % preset is created
            app.dockerPresets = newPresets;
            app.activePresetsTable = 1:numel(newPresets);

            % We will be using the label as a handle.  Make sure they're
            % unique
            labels = [newPresets.label];
            labelsLower = lower(labels);

            for i = 1:numel(labelsLower)-1
                dupmask = strcmp(labelsLower(i), labelsLower(i+1:end));
                if any(dupmask)
                    for idx = find(dupmask)
                        j = i + idx; % actual index in labels
                        % Generate unique label with suffix
                        k = 2;
                        oldLabel = labels(j);
                        newLabel = oldLabel + " " + k;
                        while any(strcmpi(newLabel, labels))
                            k = k + 1;
                            newLabel = oldLabel + " " + k;
                        end
                        newPresets(j).label = newLabel;
                        labels(j) = newLabel;
                        labelsLower(j) = lower(newLabel);
                        fprintf("Duplicate found: '%s' → renamed to '%s'\n", oldLabel, newLabel);
                    end
                end
            end
            

        end

        %%  Read the Matlab ISETDocker prefs slots        
        function newPref = getDockerPrefs(app)
            % check to see if the pref exists
            newPref = app.dockerPresetTemplate;
            if ~ispref("ISETDocker")
                % FIXME -- make sure we default cleanly
                %if not return null prefs
                return
            end
            % get the ISETDocker pref
            prefs = getpref("ISETDocker");

            % cycle through the pref fields
            fields = fieldnames(prefs);
            for i = 1:numel(fields)
                prefName = fields{i};
                name = prefName;
                value = prefs.(name);
               
                % First make sure it's a valid field name
                if ~isfield(newPref, name)
                    if ismember(name, app.ignoreFields)
                    % this is where we deal with 'batch', 'PBRTContainer', etc.
                    % put it on a special list and go to the next
                        app.ignoredPrefs.(name) = value;
                        continue
                %{
                    elseif (aliased)
                        % check for aliasing here
                %}
                    else
                        % not ignored, not aliased, not a known field
                        error("Bad entry in ISETDocker pref: %s is not a known field", ...
                            name);
                    end
                end
                % name is good, let's clean up value

                % we want value to be a string
                %
                if isstring(value)
                    value = app.cleanStr(value);
                elseif ischar(value)
                    value = app.cleanStr(string(value));
                elseif isnumeric(value) && isscalar(value)
                    value = string(value);
                else
                    %Probably not ok
                    error("Bad entry in ISETDocker pref: %s has type %s, but should be char or string", ...
                        name, class(value));
                end
                newPref.(name) = value;
            end

            % where should we keep the ones we recognize, but don't support
            % here.  Like the PBRTcontainer, which if we're changing
            % anything  in the prefs, we should probably clear.
            
            app.curPrefs = newPref;
            
        end
        
        %% Create uieditfields for all the struct elements
        function [grid,handles] = buildFieldsPanel(app, parentPanel, baseStruct, pTag, valueChangedFcn)

            % Get the struct we're populating from
            fieldNames = fieldnames(baseStruct);
            numFields = numel(fieldNames);

            % Create a grid layout inside PrefsPanel
            grid = uigridlayout(parentPanel, [numFields, 2]);
            grid.RowSpacing = 5;
            grid.ColumnSpacing = 10;
            grid.RowHeight = repmat({30}, 1, numFields);
            grid.ColumnWidth = {'1x', '2x'};


            % Store the component handles in a parallel struct
            for i = 1:numFields
                fieldName = fieldNames{i};
                % for now just use the field name. Later, maybe put in spaces
                % labelText = insertSpaces(fieldName);
                % or
                % labelText = fieldtolabel(fieldName, pTag);
                %
                labelText = fieldName;


                % Create Label
                uilabel(grid, ...
                    'Text', labelText, ...
                    'HorizontalAlignment', 'left'...
                );

                % Create Edit Field
                ef = uieditfield(grid, ...
                   'BackgroundColor', app.lockedColor, ...
                   'Tooltip', 'foo', ...
                   'Editable', 'off' ...
                );

                % Later, we may create panel specific callbacks
                % for now let the work happen in the callback function
                ef.UserData = fieldName;
                ef.ValueChangedFcn = @(src,event) valueChangedFcn(src,event);
                handles.(fieldName) = ef;
            end
        end
        
        %% put the current values into the edit fields in the panels
        function populateEditFields(app, uiHandles, dataStruct)
            fields = fieldnames(dataStruct);
            for i = 1:numel(fields)
                f = fields{i};
                if isfield(uiHandles, f)
                    % FIXME defensive string(dataStruct.(f) ?
                    % Do we check for numbers here?  or should we do it
                    % earlier?
                    uiHandles.(f).Value = dataStruct.(f);
                end
            end
        end
        
        %% NYI
        function newPresets = salvagePresetsFile(app, decodedData)
            warning("Salvage not yet implemented");
            return;
        end
        
        %% Find the preset from the label
        function idx = findpreset(app, label)
            %look for label in active presets
            activePresets = app.dockerPresets(app.activePresetsTable);
            idx = find(strcmpi([activePresets.label], label),1);
         
            if numel(idx) > 1 % we shouldn't have more than one
                error("Duplicate labels in activePresets: %s", label);
            end
            % if there is no match we're returning idx = [],
            % if there is 1 match, we're returning it's index
        end
        
        %% Generic ValueChanged callback for Pref Panel Edit Fields
        function PrefFieldChanged(app, src, event)
            %the field name is the UserData
            fieldName = src.UserData;
            % hook for more individual handling
            switch fieldName
                % FIXME are there special cases?
                otherwise
                    app.curPrefs.(fieldName) = string(src.Value);
                    app.curPrefsChanged = true;
            end
        end
  
        %% find an empty slot, allocate more if needed
        function index = getEmptyPresetSlot(app)
            unusedSlots = setdiff(1:numel(app.dockerPresets), app.activePresetsTable);
            if ~isempty(unusedSlots)
                index = unusedSlots(1);
            else
                % double the size; it's actually not a big structure, so we
                % may as well have extra space and minimize the allocate/copy
                % cycles we have to do
                oldLen = numel(app.dockerPresets);
                growby = max(oldLen, 4);
                app.dockerPresets(oldLen+1:oldLen+growby) = repmat(app.dockerPresetTemplate, growby, 1);
                index = oldLen + 1; % first new slot
            end  
        end
        
        %% update the dropdown menu after changing the activePresetsTable
        function updatePresetDropDown(app, idx)
            
            labels = [app.dockerPresets(app.activePresetsTable).label];
            app.PresetDropDown.Items = labels;
            app.PresetDropDown.Value = labels(idx);
            app.curIdx = idx;
            
        end
        
        %% Convert jsondecode output to a structs of strings
        function convertedPresets = convertDecodedPresets(app, structArray)
            % The underlying JSON file should be a struct of strings.
            % For historical reasons, jsondecode converts strings into chat arrays.
            % So the first step is to convert char arrays into  strings
            % while we've here, trim leading and trailing whitespace
            % collapse internal whitespace in labels

            fields = fieldnames(structArray);
            for i = 1:numel(structArray)
                for j = 1:numel(fields)
                    f = fields{j};
                    val = structArray(i).(f);

                    % Check for bad types and convert

                    if ischar(val)
                        val = string(val);
                    elseif iscell(val) && all(cellfun(@ischar, val))
                        error("convertCodedPreset: %s: arrays of strings are not allowed yet", ...
                            f);
                    else
                        % We require all chars all the time
                        % print out type info
                        error("convertDecodedPreset: field '%s' is not char, but %s", ...
                             f, class(val));
                    end
                    % if it's a character array, make it a string
                    % if it's a cell array of character, make it a string
                    if isequal(f, 'label')
                        newval = app.cleanLabel(val);
                    else
                        newval = app.cleanStr(val);
                    end
                    structArray(i).(fields{j}) = newval;
                    if ~isequal(newval, val)
                        valJoined = join(string(val), ", ");
                        warning("trimmed excess whitespace from '%s'", valJoined);
                    end
                end
            end
            convertedPresets = structArray;
        end
       
        %% remove leading and trailing spaces, collapse internal spaces
        function strOut = cleanStr(app, strIn)
            strOut = regexprep(strIn, '^\s+|\s+$', '');
        end
    
        
        function labelOut = cleanLabel(app, labelIn)
            labelOut = regexprep(labelIn, '^\s+|\s+(?=\s)|\s+$', ''); 
        end
        %% refresh the presets panel when there's been a change
        function updatePresetPanel(app, idx)
            % set up the menu and fill in the edit fields
            app.updatePresetDropDown(idx);            %
            % update the menu and populate the fields
            slot = app.activePresetsTable(idx);
            app.populateEditFields(app.presetFields, app.dockerPresets(slot));
        end
        
        function PresetFieldChanged(app, src, event)
            fieldName = src.UserData;
            switch (fieldName)
                % hook for individualized processing
            otherwise
                slot = app.activePresetsTable(app.curIdx);
                app.dockerPresets(slot).(fieldName) = src.Value;
                app.dockerPresetsChanged = true;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %
            % fun things to know about
            %
            app.userName = getenv("USER");

            %
            % create a Presets array and fill it in
            %
            app.dockerPresets = app.dockerPresetTemplate([]);
            app.sitePresets = fullfile(piRootPath, 'docker/config','dockerpresets.json');
            app.myPresets = fullfile(piRootPath, 'local/config','dockerpresets.json');           
            %
            % get the list of presets
            % when we return, we should have gotten presets from one of the
            % files or made some up or thrown an error
            %
            app.getDockerPresets();

            idx = 1;
            app.curIdx = idx;
            curPreset = app.dockerPresets(app.activePresetsTable(idx));
            % create the edit fields in the preset panel
            [app.presetGridHandle, app.presetFields] = app.buildFieldsPanel( ...
                app.PsFieldsPanel, curPreset, 'Preset', ...
                @(src,event) app.PresetFieldChanged(src,event) );

            % Update the dropdown menu from the preset array
            app.updatePresetPanel(idx);

            %
            % Now the current Prefs
            %
            app.getDockerPrefs();


            %
            % create the Prefs panel and populate its fields
            %   
            [app.prefGridHandle, app.prefFields] = app.buildFieldsPanel( ...
                app.PfFieldsPanel, app.curPrefs, 'Prefs', ...
                @(src,event) app.PrefFieldChanged(src,event) );

            app.ISETDockerPrefPanel.Title = "Current ISETDocker Pref";

            app.populateEditFields(app.prefFields, app.curPrefs);
            
        end

        % Value changed function: PresetDropDown
        function PresetDropDownValueChanged(app, event)
            % When we choose a pull down, we show the selection update curIdx
            % and fill the edit fields.
            idx = event.ValueIndex;
            app.curIdx = idx;
            preset = app.dockerPresets(app.activePresetsTable(idx));
            app.populateEditFields(app.presetFields, preset);
        end

        % Button pushed function: CopytoPrefButton
        function CopytoPrefButtonPushed(app, event)
            % write the current preset to the current pref
            preset = app.dockerPresets(app.activePresetsTable(app.curIdx));
            app.curPrefs = preset;
            % update the pref panel
            app.populateEditFields(app.prefFields, app.curPrefs);
            newTitle = sprintf("New Pref from '%s'", preset.label);
            app.ISETDockerPrefPanel.Title = newTitle;
            app.curPrefsChanged = true;
        end

        % Callback function: SetPrefButton, SetPrefMenu
        function SetPrefselected(app, event)
            % write out the new prefs

            %clear the old pref structure
            rmpref('ISETDocker');

            % go through app.curPref and set each field in ISETDocker           
            fields = fieldnames(app.curPrefs);
            for i = 1:numel(fields)
                setpref('ISETDocker', fields{i}, app.curPrefs.(fields{i}));
            end
            app.curPrefsChanged = false;
%{
%   just make the isgnored fields disappear for now
            extras = fieldnames(app.ignoredPrefs);
            for i = 1:numel(extras)
                setpref('ISETDocker', extras{i}, app.ignoredPrefs.(extras{i}))
            end
%}
        end

        % Value changed function: EditFieldsCheckBox
        function EditFieldsCheckboxValueChanged(app, event)
            % the value has been changed by the time this function is called
            editable = app.EditFieldsCheckBox.Value;

            % now put the individual edit fields into the same state
            fields = fieldnames(app.prefFields);
            for i =1:numel(fields)
                field = app.prefFields.(fields{i});
                field.Editable = editable;
                if editable
                    field.BackgroundColor = app.editableColor;
                else
                    field.BackgroundColor = app.lockedColor;
                end
                
            end
        end

        % Callback function: GetPrefButton, GetPrefMenu
        function GetPrefSelected(app, event)
            % call app.getDockerPrefs and populate the fields
            % we don't need to redo the UI editfields
            app.getDockerPrefs();
            app.populateEditFields(app.prefFields, app.curPrefs);  
            app.ISETDockerPrefPanel.Title = "Current ISETDocker Pref";

        end

        % Button pushed function: AddasPresetButton
        function AddAsPresetButtonPushed(app, event)
            % Copy the values in curPref into a dockerPreset slot
            % check for label duplication
            %
            label = app.curPrefs.label;
            if isempty(label) || all(isspace(label))
                % dialog box: tell them blank's not okay
                uialert(app.UIFigure, ...
                    'In order to create a preset, you must add a label to the Pref field', ...
                    'No Preset Label');
                return;
            end

            label = app.cleanLabel(label);
            idx = findpreset(app, label);
            if isempty(idx)
                slot = getEmptyPresetSlot(app);
            
                % we now have a  for a preset slot to write into
                % write into the slot and move it to the top
                app.activePresetsTable = [slot app.activePresetsTable];
                idx = 1;
            else
                queststring = sprintf('"%s" exists in Presets. Overwrite existing preset?', label);
                answer = questdlg(queststring, 'Overwrite existing Preset?', 'No');
                switch answer
                case 'Yes'
                     % write the Prefs into this Preset slot                
                     slot = app.activePresetsTable(idx);
                otherwise
                    uialert(app.UIFigure, ...
                        'Change the label in the Pref panel so that it is unique!','Label Already In Use');
                    return;
                end
            end
            % we now have a slot to write the prefs into
            app.dockerPresets(slot) = app.curPrefs;

            % update the menu and populate the fields
            app.curIdx = idx;
            app.updatePresetPanel(idx);
        end

        % Button pushed function: DeletePresetButton
        function DeletePresetButtonPushed(app, event)
            % remove curIdx from activePresetsTable,
            %           
            idx = app.curIdx;
            app.activePresetsTable(idx) = [];
            app.dockerPresetsChanged = true;
            
            if isempty(app.activePresetsTable)
                app.dockerPresets = [ app.dockerPresetTemplate([]) ; app.emergencyDefault];
                app.activePresetsTable = 1;
            end

            % update the preset panel
            app.curIdx = 1;
            app.updatePresetPanel(1);
        end

        % Menu selected function: SavePresetsMenu
        function SavePresetsMenuSelected(app, event)
            % Write out these presets as a JSON file
            jsonFile = app.myPresets;

            activePresets = app.dockerPresets(app.activePresetsTable);
            jsonStr = jsonencode(activePresets, "PrettyPrint",true);
            jsonStr = [ jsonStr newline ];
            fid = fopen(jsonFile, "w"); % open presets file 
            if fid == -1
                error('Could not open %s for writing', jsonFile);
            end
            % FIXME more better error handling
            fwrite(fid, jsonStr, 'char');
            fclose(fid);
            app.dockerPresetsChanged = false;
        end

        % Menu selected function: LoadPresetsMenu
        function LoadPresetsMenuSelected(app, event)
            % load the dockerPresets from the file
            % FIXME check for unsaved changes
            app.getDockerPresets();
            idx = 1;

            % Update the preset panel
            app.updatePresetPanel(idx);
            app.curIdx = idx;
            app.dockerPresetsChanged = false;
        end

        % Callback function: ValidateButton, ValidatePrefMenu
        function ValidatePrefSelected(app, event)
            report = isetdocker.validatePrefStruct(app.curPrefs, ...
                'checkDockerContext', true);
            message = isetdocker.validationMessage(report);
            if report.ok
                title = 'ISETDocker Prefs Valid';
                icon = 'success';
            else
                title = 'ISETDocker Prefs Need Attention';
                icon = 'warning';
            end
            uialert(app.UIFigure, message, title, 'Icon', icon);
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            % maybe check to see it anything should be saved?
            if app.dockerPresetsChanged
                % write presets out
            end
            app.delete ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '  File  ';
            app.LoadPresetsMenu = uimenu(app.FileMenu);
            app.LoadPresetsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPresetsMenuSelected, true);
            app.LoadPresetsMenu.Text = 'Load Presets';
            app.SavePresetsMenu = uimenu(app.FileMenu);
            app.SavePresetsMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePresetsMenuSelected, true);
            app.SavePresetsMenu.Text = 'Save Presets';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = '  Edit  ';
            app.GetPrefMenu = uimenu(app.EditMenu);
            app.GetPrefMenu.MenuSelectedFcn = createCallbackFcn(app, @GetPrefSelected, true);
            app.GetPrefMenu.Text = 'Get Pref';
            app.SetPrefMenu = uimenu(app.EditMenu);
            app.SetPrefMenu.MenuSelectedFcn = createCallbackFcn(app, @SetPrefselected, true);
            app.SetPrefMenu.Text = 'Set Pref';
            app.ValidatePrefMenu = uimenu(app.EditMenu);
            app.ValidatePrefMenu.MenuSelectedFcn = createCallbackFcn(app, @ValidatePrefSelected, true);
            app.ValidatePrefMenu.Text = 'Validate Pref';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PresetsPanel = uipanel(app.GridLayout2);
            app.PresetsPanel.Title = 'Presets';
            app.PresetPanelGrid = uigridlayout(app.PresetsPanel);
            app.PsFieldsPanel = uipanel(app.PresetPanelGrid);
            app.PsFieldsPanelGrid = uigridlayout(app.PsFieldsPanel);
            app.PresetDropDownLabel = uilabel(app.PresetPanelGrid);
            app.PresetDropDownLabel.Text = 'Preset';
            app.PresetDropDown = uidropdown(app.PresetPanelGrid);
            app.PresetDropDown.Items = {'Local CPU', '<New Preset>'};
            app.PresetDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetDropDownValueChanged, true);
            app.PresetDropDown.Placeholder = '<Choose a Preset>';
            app.PresetDropDown.Value = 'Local CPU';
            app.CopytoPrefButton = uibutton(app.PresetPanelGrid, 'push');
            app.CopytoPrefButton.ButtonPushedFcn = createCallbackFcn(app, @CopytoPrefButtonPushed, true);
            app.CopytoPrefButton.Text = 'Copy to Pref';
            app.DeletePresetButton = uibutton(app.PresetPanelGrid, 'push');
            app.DeletePresetButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePresetButtonPushed, true);
            app.DeletePresetButton.Text = 'Delete Preset';
            app.ISETDockerPrefPanel = uipanel(app.GridLayout2);
            app.ISETDockerPrefPanel.Title = 'ISETDocker Pref';
            app.PrefsPanelGrid = uigridlayout(app.ISETDockerPrefPanel);
            app.PfFieldsPanel = uipanel(app.PrefsPanelGrid);
            app.PfFieldsPanelGrid = uigridlayout(app.PfFieldsPanel);
            app.SetPrefButton = uibutton(app.PrefsPanelGrid, 'push');
            app.SetPrefButton.ButtonPushedFcn = createCallbackFcn(app, @SetPrefselected, true);
            app.SetPrefButton.Tooltip = {'use setpref() to make these values the new  ISETDocker pref'};
            app.SetPrefButton.Text = 'Set Pref';
            app.AddasPresetButton = uibutton(app.PrefsPanelGrid, 'push');
            app.AddasPresetButton.ButtonPushedFcn = createCallbackFcn(app, @AddAsPresetButtonPushed, true);
            app.AddasPresetButton.Tooltip = {'Copy these fields in as a new preset'};
            app.AddasPresetButton.Text = 'Add as Preset';
            app.GetPrefButton = uibutton(app.PrefsPanelGrid, 'push');
            app.GetPrefButton.ButtonPushedFcn = createCallbackFcn(app, @GetPrefSelected, true);
            app.GetPrefButton.Tooltip = {'use getpref() to get the current ISETDocker pref'};
            app.GetPrefButton.Text = 'Get Pref';
            app.ValidateButton = uibutton(app.PrefsPanelGrid, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidatePrefSelected, true);
            app.ValidateButton.Tooltip = {'Validate the values of the current fields'};
            app.ValidateButton.Text = 'Validate Pref';
            app.EditFieldsCheckBox = uicheckbox(app.PrefsPanelGrid);
            app.EditFieldsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditFieldsCheckboxValueChanged, true);
            app.EditFieldsCheckBox.Tooltip = {'check this box to make the Pref fields editable'};
            app.EditFieldsCheckBox.Text = 'Edit Fields';
            app.ISETDockerPrefsEditorLabel = uilabel(app.GridLayout);
            app.ISETDockerPrefsEditorLabel.Text = 'ISETDocker Prefs Editor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DockerPref

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end