classdef remoteDocker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        FileMenu_Close                  matlab.ui.container.Menu
        RemotePBRTResourcesEditField    matlab.ui.control.EditField
        RemotePBRTResourcesEditFieldLabel  matlab.ui.control.Label
        PBRTResourcesEditField_2        matlab.ui.control.EditField
        PBRTResourcesEditField_2Label   matlab.ui.control.Label
        RemoteWorkDirEditField          matlab.ui.control.EditField
        RemoteWorkDirEditFieldLabel_2   matlab.ui.control.Label
        RemoteUserEditField             matlab.ui.control.EditField
        RemoteUserEditFieldLabel        matlab.ui.control.Label
        DockerImageEditField            matlab.ui.control.EditField
        DockerImageEditFieldLabel       matlab.ui.control.Label
        DeviceIDEditField               matlab.ui.control.EditField
        DeviceIDEditFieldLabel          matlab.ui.control.Label
        DeviceEditField                 matlab.ui.control.EditField
        DeviceEditFieldLabel_2          matlab.ui.control.Label
        RemoteHostEditField             matlab.ui.control.EditField
        RemoteHostEditFieldLabel_2      matlab.ui.control.Label
        DockerContextEditField          matlab.ui.control.EditField
        DockerContextEditFieldLabel     matlab.ui.control.Label
        CurrentPresetLabel              matlab.ui.control.Label
        CurrentISETDockerPrefsLabel     matlab.ui.control.Label
        ISET3dRenderParametersLabel     matlab.ui.control.Label
        choosePresetDropDown            matlab.ui.control.DropDown
        dockerContextEditField          matlab.ui.control.EditField
        remoteHostEditField             matlab.ui.control.EditField
        deviceEditField                 matlab.ui.control.EditField
        deviceIDEditField               matlab.ui.control.NumericEditField
        DeviceIDEditField_2Label        matlab.ui.control.Label
        dockerImageEditField            matlab.ui.control.EditField
        remoteUserEditField             matlab.ui.control.EditField
        remoteWorkDirEditField          matlab.ui.control.EditField
        RemotePBRTResourcesEditField_2  matlab.ui.control.EditField
        PBRTResourcesEditField          matlab.ui.control.EditField
        PBRTResourcesLabel              matlab.ui.control.Label
        remotePBRTResourcesLabel        matlab.ui.control.Label
        DeviceEditFieldLabel            matlab.ui.control.Label
        DockerContextLabel              matlab.ui.control.Label
        RemoteHostEditFieldLabel        matlab.ui.control.Label
        RemoteWorkDirEditFieldLabel     matlab.ui.control.Label
        RemoteuserEditFieldLabel        matlab.ui.control.Label
        DockerimageEditFieldLabel       matlab.ui.control.Label
        ChoosePresetLabel               matlab.ui.control.Label
        writePreferencesButton          matlab.ui.control.Button
    end

    % This app has a few related functions
    %   1) choose from among several preset collections of docker render
    %   parameters and write that set of parameters out to the ISETDocker prefs
    %   for Iset3d
    %
    %   2) add, delete, and edit presets
    %
    %   3) do some sanity checking on the presets before Iset3d is invoked
    %
    %   There's some naming cleanup to do.  The field names and the names
    %   in the preset structure should be more parallel.  Right now the
    %   case conventions disagree for the most part
    %
    
    properties (Access = private)
        % We'll have a little section for the array of dockerPreset structures
        % and the functions that set it up and play with it.
        dockerPresets = struct([])
        fieldMappings = {}  % Will hold name pairs for field ↔ UI mapping
        currentPreset = struct()  % this is the preset we're working on now
    end

    methods (Access = private)
        
        function initializeFieldMappings(app)
            %
            % we manage the UI/field name mapping in a table
            %
            app.fieldMappings = {
                'dockerContext', 'dockerContextEditField';
                'remoteHost', 'remoteHostEditField';
                'device', 'deviceEditField';
                'deviceID', 'deviceIDEditField';
                'dockerImage', 'dockerImageEditField';
                'remoteUser', 'remoteUserEditField'
                'workDir', 'remoteWorkDirEditField';                
                'remotePBRTResources', 'remotePBRTResourcesEditField';
                'PBRTResources', 'PBRTResourcesEditField';
            };
        end

        function populatefields(app, dockerPreset)
            % use the table to take us through the the form

            for i = 1:size(app.fieldMappings, 1)
                structField = app.fieldMappings{i, 1};
                uiField = app.fieldMappings{i, 2};

                if isfield(dockerPreset, structField)
                    app.(uiField).Value = dockerPreset.(structField);
                end
            end          
        end
       
        
        function  getdockerPresets(app)

            configDir = fullfile(piRootPath,'local/config');
            presetsFile = fullfile(configDir,'dockerpresets.json');
            
            if ~isfile(presetsFile)
                warning('Presets file not found %s', presetsFile);
                app.dockerPresets = struct([]);
                return;
            end
            try
                rawtext = fileread(presetsFile);
                decodedData = jsondecode(rawtext);

                if isstruct(decodedData) && ~isempty(decodedData)
                    app.dockerPresets = decodedData;
                else
                    warning('JSON file is empty or has invalid structure.');
                    app.dockerPresets = struct ([]);
                end
            catch err
                warning(err.identifier, 'Error reading JSON file: %s', err.message);
                app.dockerPresets = struct([]);
            end
        end
        
        function preset = findpreset(app, label)
           preset = struct(); 
           
           for i = 1:numel(app.dockerPresets)
                if strcmpi(app.dockerPresets(i).label, label)
                    preset = app.dockerPresets(i);
                    return
                end
           end
           warning('Preset "%s" not found.', label)
        end
       
        function refreshApp(app)
        % probably made redundant by populate fields

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % what should we have as static defaults?  A local cpu version?
            % See if there's a json file that we can read
            % once we've dealt with the json file we can see if there is
            % an ISETDocker pref that wants to be made into a preset
            % if yes, read it in and build the menu
            % %otherwise

            initializeFieldMappings(app);

            % we should get username and so on as well
            

            % get a list of render contexts and put it into the dropdown
            % I think we should keep the file in the iset/local directory
            try
                getdockerPresets(app);
                % Assign the cell array to the DropDown's Items property.
                app.choosePresetDropDown.Items = {app.dockerPresets.label};
            catch ME
                % Handle errors (e.g., file not found).
                uialert(app.UIFigure, ME.message, 'Error');
                app.choosePresetDropDown.Items = {'Error reading file'}; % provide an error message in the dropdown.
            end
            % Maybe getpref("isetdocker", "contextLabel") for the initial
            % value?
            app.currentPreset = app.dockerPresets(1);
            populatefields(app, app.currentPreset);
        end

        % Menu selected function: FileMenu_Close
        function fileMenuClose(app, event)
            app.delete;
        end

        % Value changed function: choosePresetDropDown
        function choosePreset(app, event)
            % When we choose a pull down, we reader the selection and
            % fill the other fields.
            label = app.choosePresetDropDown.Value;
            
            dockerPreset = findpreset(app,label);
            populatefields(app, dockerPreset);

    %           thisD = isetdocker;

           
            % might change where we get things...
            % this is all ease-of-use stuff to generate some presets.  
            % When we're doing stuff we just
            % use getpref for the current setup and ust go.  We can figure
            % out wh
            %

        end

        % Value changed function: dockerContextEditField
        function dockerContextEditFieldValueChanged(app, event)
            value = app.dockerContextEditField.Value;
            app.currentPreset.renderContext = value;
        end

        % Value changed function: remoteHostEditField
        function remoteHostEditFieldValueChanged(app, event)
            value = app.remoteHostEditField.Value;
            app.currentPreset.remoteHost = value;
        end

        % Value changed function: deviceEditField
        function deviceEditFieldValueChanged(app, event)
            value = app.deviceEditField.Value;
            app.currentPreset.device = value;            
        end

        % Value changed function: deviceIDEditField
        function deviceIDEditFieldValueChanged(app, event)
            value = app.deviceIDEditField.Value;
            app.currentPreset.deviceID = value;
        end

        % Value changed function: dockerImageEditField
        function dockerImageEditFieldValueChanged(app, event)
            value = app.dockerImageEditField.Value;
            app.currentPreset.dockerImage = value;
        end

        % Value changed function: remoteUserEditField
        function remoteUser(app, event)
            % This might change the remote working directory, too,
            % when we get that smart.
            value = app.remoteUserEditField.Value;
            app.currentPreset.remoteUser = value;          
        end

        % Value changed function: remoteWorkDirEditField
        function remoteWorkDirEditFieldValueChanged(app, event)
            value = app.remoteWorkDirEditField.Value;
            app.currentPreset.remoteWorkdir = value;
        end

        % Value changed function: RemotePBRTResourcesEditField_2
        function RemotePBRTResourcesEditField_2ValueChanged(app, event)
            value = app.RemotePBRTResourcesEditField_2.Value;
            app.currentPreset.remotePBRTResources = value;
        end

        % Value changed function: PBRTResourcesEditField
        function PBRTResourcesEditFieldValueChanged(app, event)
            value = app.PBRTResourcesEditField.Value;
            app.currentPreset.PBRTResources = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.FileMenu_Close = uimenu(app.FileMenu);
            app.FileMenu_Close.MenuSelectedFcn = createCallbackFcn(app, @fileMenuClose, true);
            app.FileMenu_Close.Text = 'Quit';
            app.writePreferencesButton = uibutton(app.UIFigure, 'push');
            app.writePreferencesButton.Text = 'Write to ISETDocker Preferences';
            app.ChoosePresetLabel = uilabel(app.UIFigure);
            app.ChoosePresetLabel.Text = 'Choose Preset';
            app.DockerimageEditFieldLabel = uilabel(app.UIFigure);
            app.DockerimageEditFieldLabel.Text = 'Docker image';
            app.RemoteuserEditFieldLabel = uilabel(app.UIFigure);
            app.RemoteuserEditFieldLabel.Text = 'Remote user';
            app.RemoteWorkDirEditFieldLabel = uilabel(app.UIFigure);
            app.RemoteWorkDirEditFieldLabel.Text = 'Remote Work Dir';
            app.RemoteHostEditFieldLabel = uilabel(app.UIFigure);
            app.RemoteHostEditFieldLabel.Text = 'Remote Host';
            app.DockerContextLabel = uilabel(app.UIFigure);
            app.DockerContextLabel.Text = 'Docker Context';
            app.DeviceEditFieldLabel = uilabel(app.UIFigure);
            app.DeviceEditFieldLabel.Text = 'Device';
            app.remotePBRTResourcesLabel = uilabel(app.UIFigure);
            app.remotePBRTResourcesLabel.Text = 'Remote PBRT Resources';
            app.PBRTResourcesLabel = uilabel(app.UIFigure);
            app.PBRTResourcesLabel.Text = 'PBRT Resources';
            app.PBRTResourcesEditField = uieditfield(app.UIFigure, 'text');
            app.PBRTResourcesEditField.ValueChangedFcn = createCallbackFcn(app, @PBRTResourcesEditFieldValueChanged, true);
            app.PBRTResourcesEditField.Editable = 'off';
            app.PBRTResourcesEditField.Value = '/acorn/data/iset/PBRTResources';
            app.RemotePBRTResourcesEditField_2 = uieditfield(app.UIFigure, 'text');
            app.RemotePBRTResourcesEditField_2.ValueChangedFcn = createCallbackFcn(app, @RemotePBRTResourcesEditField_2ValueChanged, true);
            app.RemotePBRTResourcesEditField_2.Editable = 'off';
            app.RemotePBRTResourcesEditField_2.Value = '/acorn/data/iset/PBRTResources';
            app.remoteWorkDirEditField = uieditfield(app.UIFigure, 'text');
            app.remoteWorkDirEditField.ValueChangedFcn = createCallbackFcn(app, @remoteWorkDirEditFieldValueChanged, true);
            app.remoteWorkDirEditField.Editable = 'off';
            app.remoteWorkDirEditField.Value = 'home/wandell/ISETRemoteRender';
            app.remoteUserEditField = uieditfield(app.UIFigure, 'text');
            app.remoteUserEditField.ValueChangedFcn = createCallbackFcn(app, @remoteUser, true);
            app.remoteUserEditField.Editable = 'off';
            app.remoteUserEditField.Value = 'wandell';
            app.dockerImageEditField = uieditfield(app.UIFigure, 'text');
            app.dockerImageEditField.ValueChangedFcn = createCallbackFcn(app, @dockerImageEditFieldValueChanged, true);
            app.dockerImageEditField.Editable = 'off';
            app.dockerImageEditField.Value = 'digitalprodev/pbrt-v4-gpu-ampere-ti';
            app.DeviceIDEditField_2Label = uilabel(app.UIFigure);
            app.DeviceIDEditField_2Label.Text = 'Device ID';
            app.deviceIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.deviceIDEditField.ValueChangedFcn = createCallbackFcn(app, @deviceIDEditFieldValueChanged, true);
            app.deviceIDEditField.Editable = 'off';
            app.deviceIDEditField.Value = 1;
            app.deviceEditField = uieditfield(app.UIFigure, 'text');
            app.deviceEditField.ValueChangedFcn = createCallbackFcn(app, @deviceEditFieldValueChanged, true);
            app.deviceEditField.Editable = 'off';
            app.deviceEditField.Value = 'GPU';
            app.remoteHostEditField = uieditfield(app.UIFigure, 'text');
            app.remoteHostEditField.ValueChangedFcn = createCallbackFcn(app, @remoteHostEditFieldValueChanged, true);
            app.remoteHostEditField.Editable = 'off';
            app.remoteHostEditField.Value = 'orange.stanford.edu';
            app.dockerContextEditField = uieditfield(app.UIFigure, 'text');
            app.dockerContextEditField.ValueChangedFcn = createCallbackFcn(app, @dockerContextEditFieldValueChanged, true);
            app.dockerContextEditField.Editable = 'off';
            app.dockerContextEditField.Value = 'remote-orange';
            app.choosePresetDropDown = uidropdown(app.UIFigure);
            app.choosePresetDropDown.Items = {'orange 3090 (remote)', 'orange 3070 (remote)', 'mux 3080 (remote)', 'mux 2080 (remote)', 'cnidl (remote)', 'local gpu', 'local cpu'};
            app.choosePresetDropDown.ValueChangedFcn = createCallbackFcn(app, @choosePreset, true);
            app.choosePresetDropDown.Value = 'orange 3090 (remote)';
            app.ISET3dRenderParametersLabel = uilabel(app.UIFigure);
            app.ISET3dRenderParametersLabel.Text = 'ISET3d Render Parameters';
            app.CurrentISETDockerPrefsLabel = uilabel(app.UIFigure);
            app.CurrentISETDockerPrefsLabel.Text = 'Current ISETDocker Prefs';
            app.CurrentPresetLabel = uilabel(app.UIFigure);
            app.CurrentPresetLabel.Text = 'Current Preset';
            app.DockerContextEditFieldLabel = uilabel(app.UIFigure);
            app.DockerContextEditFieldLabel.Text = 'Docker Context';
            app.DockerContextEditField = uieditfield(app.UIFigure, 'text');
            app.DockerContextEditField.Editable = 'off';
            app.RemoteHostEditFieldLabel_2 = uilabel(app.UIFigure);
            app.RemoteHostEditFieldLabel_2.Text = 'Remote Host';
            app.RemoteHostEditField = uieditfield(app.UIFigure, 'text');
            app.RemoteHostEditField.Editable = 'off';
            app.DeviceEditFieldLabel_2 = uilabel(app.UIFigure);
            app.DeviceEditFieldLabel_2.Text = 'Device';
            app.DeviceEditField = uieditfield(app.UIFigure, 'text');
            app.DeviceEditField.Editable = 'off';
            app.DeviceIDEditFieldLabel = uilabel(app.UIFigure);
            app.DeviceIDEditFieldLabel.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.UIFigure, 'text');
            app.DeviceIDEditField.Editable = 'off';
            app.DockerImageEditFieldLabel = uilabel(app.UIFigure);
            app.DockerImageEditFieldLabel.Text = 'Docker Image';
            app.DockerImageEditField = uieditfield(app.UIFigure, 'text');
            app.DockerImageEditField.Editable = 'off';
            app.RemoteUserEditFieldLabel = uilabel(app.UIFigure);
            app.RemoteUserEditFieldLabel.Text = 'Remote User';
            app.RemoteUserEditField = uieditfield(app.UIFigure, 'text');
            app.RemoteUserEditField.Editable = 'off';
            app.RemoteWorkDirEditFieldLabel_2 = uilabel(app.UIFigure);
            app.RemoteWorkDirEditFieldLabel_2.Text = 'Remote Work Dir';
            app.RemoteWorkDirEditField = uieditfield(app.UIFigure, 'text');
            app.RemoteWorkDirEditField.Editable = 'off';
            app.PBRTResourcesEditField_2Label = uilabel(app.UIFigure);
            app.PBRTResourcesEditField_2Label.Text = 'PBRT Resources';
            app.PBRTResourcesEditField_2 = uieditfield(app.UIFigure, 'text');
            app.PBRTResourcesEditField_2.Editable = 'off';
            app.RemotePBRTResourcesEditFieldLabel = uilabel(app.UIFigure);
            app.RemotePBRTResourcesEditFieldLabel.Text = 'Remote PBRT Resources';
            app.RemotePBRTResourcesEditField = uieditfield(app.UIFigure, 'text');
            app.RemotePBRTResourcesEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = remoteDocker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end